classdef AreaCuadrado_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        popupmenu1  matlab.ui.control.DropDown
        text3       matlab.ui.control.Label
        lado        matlab.ui.control.EditField
        calcular    matlab.ui.control.Button
        text2       matlab.ui.control.Label
        uipanel1    matlab.ui.container.Panel
        txtMedida   matlab.ui.control.Label
        resultado   matlab.ui.control.Label
    end

    
    methods (Access = private)
        function pushbutton2_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pushbutton2.
            
            close(AreaCuadrado)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AreaCuadrado_OpeningFcn(app, varargin)
            % --- Executes just before AreaCuadrado is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to AreaCuadrado (see VARARGIN)
            
            % Choose default command line output for AreaCuadrado
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: calcular
        function calcular_Callback(app, event)
            % --- Executes on button press in calcular.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
             a=str2num(get(handles.lado,'string'));
                     A=a^2;
                     set(handles.resultado,'string',A);
                     set(handles.txtMedida,'Visible','on');
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % --- Executes on selection change in popupmenu1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


                option=get(handles.popupmenu1,'value');
            switch (option)
                case 1
                case 2
            
                    set(handles.txtMedida,'string','Mms²');
                case 3
                   set(handles.txtMedida,'string','Cms²');
                case 4
                    set(handles.txtMedida,'string','Mts²');
                case 5
                    set(handles.txtMedida,'string','Kms²');
            
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu1
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'AreaCuadrado';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'ÁREA';
            app.uipanel1.Tag = 'uipanel1';
            app.resultado = uilabel(app.uipanel1);
            app.resultado.Tag = 'resultado';
            app.resultado.Text = '';
            app.txtMedida = uilabel(app.uipanel1);
            app.txtMedida.Tag = 'txtMedida';
            app.txtMedida.Visible = 'off';
            app.txtMedida.Text = '';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'ÁREA DE UN CUADRADO';
            app.calcular = uibutton(app.figure1, 'push');
            app.calcular.ButtonPushedFcn = createCallbackFcn(app, @calcular_Callback, true);
            app.calcular.Tag = 'calcular';
            app.calcular.Text = 'Calcular';
            app.lado = uieditfield(app.figure1, 'text');
            app.lado.Tag = 'lado';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'MEDIDA DE UN LADO';
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'Unidad de Medida', 'Milímetros', 'Centimetros', 'Metros', 'Kilometros'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'Unidad de Medida';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AreaCuadrado_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)AreaCuadrado_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end