classdef Areatriangulorectan_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        text6       matlab.ui.control.Label
        popupmenu1  matlab.ui.control.DropDown
        Calcular    matlab.ui.control.Button
        uipanel1    matlab.ui.container.Panel
        resultado   matlab.ui.control.Label
        txtMedida   matlab.ui.control.Label
        text3       matlab.ui.control.Label
        text2       matlab.ui.control.Label
        altura      matlab.ui.control.EditField
        base        matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Areatriangulorectan_OpeningFcn(app, varargin)
            % --- Executes just before Areatriangulorectan is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Areatriangulorectan (see VARARGIN)
            
            % Choose default command line output for Areatriangulorectan
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Calcular
        function Calcular_Callback(app, event)
            % --- Executes on button press in Calcular.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Altura=eval(get(handles.altura,'String'));
            Base=eval(get(handles.base,'String'));
            Total=(Base*Altura)/2;
            
            set(handles.resultado,'String',Total);
            set(handles.txtMedida,'Visible','on');
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % --- Executes on selection change in popupmenu1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            option=get(handles.popupmenu1,'value');
            switch (option)
                case 1
                case 2
            
                    set(handles.txtMedida,'string','Mms²');
                case 3
                   set(handles.txtMedida,'string','Cms²');
                case 4
                    set(handles.txtMedida,'string','Mts²');
                case 5
                    set(handles.txtMedida,'string','Kms²');
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu1
            
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Areatriangulorectan';
            app.figure1.Tag = 'figure1';
            app.base = uieditfield(app.figure1, 'text');
            app.base.Tag = 'base';
            app.altura = uieditfield(app.figure1, 'text');
            app.altura.Tag = 'altura';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'BASE';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'ALTURA';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'AREA';
            app.uipanel1.Tag = 'uipanel1';
            app.txtMedida = uilabel(app.uipanel1);
            app.txtMedida.Tag = 'txtMedida';
            app.txtMedida.Visible = 'off';
            app.txtMedida.Text = '';
            app.resultado = uilabel(app.uipanel1);
            app.resultado.Tag = 'resultado';
            app.resultado.Text = '';
            app.Calcular = uibutton(app.figure1, 'push');
            app.Calcular.ButtonPushedFcn = createCallbackFcn(app, @Calcular_Callback, true);
            app.Calcular.Tag = 'Calcular';
            app.Calcular.Text = 'CALCULAR';
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'Unidad de Medida', 'Milímetros', 'Centimetros', 'Metros', 'Kilometros', ''};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'Unidad de Medida';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = {'ÁREA TRIÁNGULO RECTÁNGULO'; ''};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Areatriangulorectan_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Areatriangulorectan_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end