classdef Biseccion_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        text11        matlab.ui.control.Label
        TXTRAIZ       matlab.ui.control.Label
        text7         matlab.ui.control.Label
        RAICES        matlab.ui.control.ListBox
        CALCULAR      matlab.ui.control.Button
        text5         matlab.ui.control.Label
        text4         matlab.ui.control.Label
        text2         matlab.ui.control.Label
        NITERACIONES  matlab.ui.control.EditField
        XB            matlab.ui.control.EditField
        XA            matlab.ui.control.EditField
        FUNCION       matlab.ui.control.EditField
        text6         matlab.ui.control.Label
        GRAFICA       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Biseccion_OpeningFcn(app, varargin)
            % --- Executes just before Biseccion is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Biseccion (see VARARGIN)
            
            % Choose default command line output for Biseccion
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: CALCULAR
        function CALCULAR_Callback(app, event)
            % --- Executes on button press in CALCULAR.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


            f=get(handles.FUNCION,'String');
            f=inline(f);
            S=str2double(get(handles.XA,'String'));
            I=str2double(get(handles.XB,'String'));
            n=str2double(get(handles.NITERACIONES,'String'));


            xlabel('Valores de (x)');
            ylabel('Valores de F(x)');


            ezplot(f);
            grid on
            xlabel('Valores de (x)');
            ylabel('Valores de F(x)');


            if f(I)*f(S)<0
                x=(I+S)/2;
            
                for k=1:n
                    x=(I+S)/2;
                     if f(I)*f(x)<0
                           S=x;
                     else if f(I)*f(x)>0
                            I=x;
                         else
                             I=S;
                         end
            
                     end
            
                     Y{k}=x;
            
                     set(handles.TXTRAIZ,'String',x);
                end
                set(handles.RAICES,'String',Y);
            else
                set(handles.TXTRAIZ,'String','cambie los valores ');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Biseccion';
            app.figure1.Tag = 'figure1';
            app.GRAFICA = uiaxes(app.figure1);
            app.GRAFICA.Tag = 'GRAFICA';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'No. Iter';
            app.FUNCION = uieditfield(app.figure1, 'text');
            app.FUNCION.Tag = 'FUNCION';
            app.XA = uieditfield(app.figure1, 'text');
            app.XA.Tag = 'XA';
            app.XB = uieditfield(app.figure1, 'text');
            app.XB.Tag = 'XB';
            app.NITERACIONES = uieditfield(app.figure1, 'text');
            app.NITERACIONES.Tag = 'NITERACIONES';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'f(x)';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'xa:';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'xb:';
            app.CALCULAR = uibutton(app.figure1, 'push');
            app.CALCULAR.ButtonPushedFcn = createCallbackFcn(app, @CALCULAR_Callback, true);
            app.CALCULAR.Tag = 'CALCULAR';
            app.CALCULAR.Text = 'CALCULAR RAIZ';
            app.RAICES = uilistbox(app.figure1);
            app.RAICES.Items = {'Iteraciones'};
            app.RAICES.Tag = 'RAICES';
            app.RAICES.Value = 'Iteraciones';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'RAIZ';
            app.TXTRAIZ = uilabel(app.figure1);
            app.TXTRAIZ.Tag = 'TXTRAIZ';
            app.TXTRAIZ.Text = '';
            app.text11 = uilabel(app.figure1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'MÉTODO DE BISECCIÓN';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Biseccion_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Biseccion_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end