classdef CALCULADORAUMG_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        uipanel1          matlab.ui.container.Panel
        RAIZ              matlab.ui.control.Button
        PORCIENTO         matlab.ui.control.Button
        BORRAR            matlab.ui.control.Button
        ABRIRCIENTIIFICA  matlab.ui.control.Button
        RESULTADO         matlab.ui.control.Label
        DIVIDIR           matlab.ui.control.Button
        MULTIPLICAR       matlab.ui.control.Button
        NUEVE             matlab.ui.control.Button
        OCHO              matlab.ui.control.Button
        SIETE             matlab.ui.control.Button
        MAS               matlab.ui.control.Button
        MENOS             matlab.ui.control.Button
        IGUAL             matlab.ui.control.Button
        TRES              matlab.ui.control.Button
        SEIS              matlab.ui.control.Button
        PUNTO             matlab.ui.control.Button
        DOS               matlab.ui.control.Button
        CINCO             matlab.ui.control.Button
        CERO              matlab.ui.control.Button
        UNO               matlab.ui.control.Button
        CUATRO            matlab.ui.control.Button
        axes1             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CALCULADORAUMG_OpeningFcn(app, varargin)
            % --- Executes just before CALCULADORAUMG is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            handles.memoria= num2cell(zeros(1,12))%Declaramos nuestra memoria con 12 digitos
            handles.i=0 %Contador
            handles.NUM=[]%Vector que almacena datos antes de operarlos
            guidata(hObject,handles)
            
            axes(handles.axes1);
            [x,map]=imread('fondo1.jpg');
            image(x)
            colormap (map);
            axis off
            hold on
            
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to CALCULADORAUMG (see VARARGIN)
            
            % Choose default command line output for CALCULADORAUMG
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: ABRIRCIENTIIFICA
        function ABRIRCIENTIIFICA_Callback(app, event)
            % --- Executes on button press in ABRIRCIENTIIFICA.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Cientifica
            close(CALCULADORAUMG)
        end

        % Button pushed function: BORRAR
        function BORRAR_Callback(app, event)
            % --- Executes on button press in BORRAR.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set(handles.RESULTADO,'String','')
            handles.memoria=num2cell(zeros(1,12))
            handles.i=0
            guidata(hObject,handles)
        end

        % Button pushed function: CERO
        function CERO_Callback(app, event)
            % --- Executes on button press in CERO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.i=handles.i+1
            if handles.i<=12
                handles.memoria(handles.i)={'0'}
                handles.Text = num2str(cell2mat(handles.memoria(1:handles.i)))
            end
            set(handles.RESULTADO, 'String',handles.Text);
            guidata(hObject,handles);
        end

        % Button pushed function: CINCO
        function CINCO_Callback(app, event)
            % --- Executes on button press in CINCO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.i=handles.i+1
            if handles.i<=12
                handles.memoria(handles.i)={'5'}
                handles.Text = num2str(cell2mat(handles.memoria(1:handles.i)))
            end
            set(handles.RESULTADO, 'String',handles.Text);
            guidata(hObject,handles);
        end

        % Button pushed function: CUATRO
        function CUATRO_Callback(app, event)
            % --- Executes on button press in CUATRO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.i=handles.i+1
            if handles.i<=12
                handles.memoria(handles.i)={'4'}
                handles.Text = num2str(cell2mat(handles.memoria(1:handles.i)))
            end
            set(handles.RESULTADO, 'String',handles.Text);
            guidata(hObject,handles);
        end

        % Button pushed function: DIVIDIR
        function DIVIDIR_Callback(app, event)
            % --- Executes on button press in DIVIDIR.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.NUM=str2double(get(handles.RESULTADO,'String'))
            set(handles.RESULTADO,'String','')
            handles.memoria=num2cell(zeros(1,12))
            handles.i=0;
            handles.operacion=4
            guidata(hObject,handles);
        end

        % Button pushed function: DOS
        function DOS_Callback(app, event)
            % --- Executes on button press in DOS.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.i=handles.i+1
            if handles.i<=12
                handles.memoria(handles.i)={'2'}
                handles.Text = num2str(cell2mat(handles.memoria(1:handles.i)))
            end
            set(handles.RESULTADO, 'String',handles.Text);
            guidata(hObject,handles);
        end

        % Button pushed function: IGUAL
        function IGUAL_Callback(app, event)
            % --- Executes on button press in IGUAL.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if(handles.operacion==1)%Suma
                handles.R=handles.NUM+str2double(handles.Text)
                set (handles.RESULTADO,'String',handles.R)
            elseif(handles.operacion==2)%Resta
                handles.R=handles.NUM-str2double(handles.Text)
                 set (handles.RESULTADO,'String',handles.R)
            elseif(handles.operacion==3)%Multiplicacion
                handles.R=handles.NUM*str2double(handles.Text)
                 set (handles.RESULTADO,'String',handles.R)
            elseif(handles.operacion==4)%Divicion
                handles.R=handles.NUM/str2double(handles.Text)
                 set (handles.RESULTADO,'String',handles.R)
            elseif(handles.operacion==5)%Porcentaje
                handles.R=handles.NUM*str2double(handles.Text)/100
                 set (handles.RESULTADO,'String',handles.R)
                 end
            guidata(hObject,handles)
        end

        % Button pushed function: MAS
        function MAS_Callback(app, event)
            % --- Executes on button press in MAS.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.NUM=str2double(get(handles.RESULTADO,'String'))
            set(handles.RESULTADO,'String','')
            handles.memoria=num2cell(zeros(1,12))
            handles.i=0;
            handles.operacion=1
            guidata(hObject,handles)
        end

        % Button pushed function: MENOS
        function MENOS_Callback(app, event)
            % --- Executes on button press in MENOS.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.NUM=str2double(get(handles.RESULTADO,'String'))
            set(handles.RESULTADO,'String','')
            handles.memoria=num2cell(zeros(1,12))
            handles.i=0;
            handles.operacion=2
            guidata(hObject,handles);
        end

        % Button pushed function: MULTIPLICAR
        function MULTIPLICAR_Callback(app, event)
            % --- Executes on button press in MULTIPLICAR.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.NUM=str2double(get(handles.RESULTADO,'String'))
            set(handles.RESULTADO,'String','')
            handles.memoria=num2cell(zeros(1,12))
            handles.i=0;
            handles.operacion=3
            guidata(hObject,handles);
        end

        % Button pushed function: NUEVE
        function NUEVE_Callback(app, event)
            % --- Executes on button press in NUEVE.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.i=handles.i+1
            if handles.i<=12
                handles.memoria(handles.i)={'9'}
                handles.Text = num2str(cell2mat(handles.memoria(1:handles.i)))
            end
            set(handles.RESULTADO, 'String',handles.Text);
            guidata(hObject,handles);
        end

        % Button pushed function: OCHO
        function OCHO_Callback(app, event)
            % --- Executes on button press in OCHO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.i=handles.i+1
            if handles.i<=12
                handles.memoria(handles.i)={'8'}
                handles.Text = num2str(cell2mat(handles.memoria(1:handles.i)))
            end
            set(handles.RESULTADO, 'String',handles.Text);
            guidata(hObject,handles);
        end

        % Button pushed function: PORCIENTO
        function PORCIENTO_Callback(app, event)
            % --- Executes on button press in PORCIENTO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.NUM=str2double(get(handles.RESULTADO,'String'))
            set(handles.RESULTADO,'String','')
            handles.memoria=num2cell(zeros(1,12))
            handles.i=0;
            handles.operacion=5
            guidata(hObject,handles);
        end

        % Button pushed function: PUNTO
        function PUNTO_Callback(app, event)
            % --- Executes on button press in PUNTO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.i=handles.i+1
            if handles.i<=12
                handles.memoria(handles.i)={'.'}
                handles.Text = num2str(cell2mat(handles.memoria(1:handles.i)))
            end
            set(handles.RESULTADO, 'String',handles.Text);
            guidata(hObject,handles);
        end

        % Button pushed function: RAIZ
        function RAIZ_Callback(app, event)
            % --- Executes on button press in RAIZ.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.NUM=str2double(get(handles.RESULTADO,'String'))
            handles.memoria=num2cell(zeros(1,12))
            handles.i=0;
            handles.R=sqrt(handles.NUM)
            set(handles.RESULTADO,'String',handles.R)
            guidata(hObject,handles)
        end

        % Button pushed function: SEIS
        function SEIS_Callback(app, event)
            % --- Executes on button press in SEIS.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.i=handles.i+1
            if handles.i<=12
                handles.memoria(handles.i)={'6'}
                handles.Text = num2str(cell2mat(handles.memoria(1:handles.i)))
            end
            set(handles.RESULTADO, 'String',handles.Text);
            guidata(hObject,handles);
        end

        % Button pushed function: SIETE
        function SIETE_Callback(app, event)
            % --- Executes on button press in SIETE.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.i=handles.i+1
            if handles.i<=12
                handles.memoria(handles.i)={'7'}
                handles.Text = num2str(cell2mat(handles.memoria(1:handles.i)))
            end
            set(handles.RESULTADO, 'String',handles.Text);
            guidata(hObject,handles);
        end

        % Button pushed function: TRES
        function TRES_Callback(app, event)
            % --- Executes on button press in TRES.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.i=handles.i+1
            if handles.i<=12
                handles.memoria(handles.i)={'3'}
                handles.Text = num2str(cell2mat(handles.memoria(1:handles.i)))
            end
            set(handles.RESULTADO, 'String',handles.Text);
            guidata(hObject,handles);
        end

        % Button pushed function: UNO
        function UNO_Callback(app, event)
            % --- Executes on button press in UNO.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.i=handles.i+1
            if handles.i<=12
                handles.memoria(handles.i)={'1'}
                handles.Text = num2str(cell2mat(handles.memoria(1:handles.i)))
            end
            set(handles.RESULTADO, 'String',handles.Text);
            guidata(hObject,handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'CALCULADORAUMG';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'BASICA';
            app.uipanel1.Tag = 'uipanel1';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.Tag = 'axes1';
            app.CUATRO = uibutton(app.uipanel1, 'push');
            app.CUATRO.ButtonPushedFcn = createCallbackFcn(app, @CUATRO_Callback, true);
            app.CUATRO.Tag = 'CUATRO';
            app.CUATRO.Text = '4';
            app.UNO = uibutton(app.uipanel1, 'push');
            app.UNO.ButtonPushedFcn = createCallbackFcn(app, @UNO_Callback, true);
            app.UNO.Tag = 'UNO';
            app.UNO.Text = '1';
            app.CERO = uibutton(app.uipanel1, 'push');
            app.CERO.ButtonPushedFcn = createCallbackFcn(app, @CERO_Callback, true);
            app.CERO.Tag = 'CERO';
            app.CERO.Text = '0';
            app.CINCO = uibutton(app.uipanel1, 'push');
            app.CINCO.ButtonPushedFcn = createCallbackFcn(app, @CINCO_Callback, true);
            app.CINCO.Tag = 'CINCO';
            app.CINCO.Text = '5';
            app.DOS = uibutton(app.uipanel1, 'push');
            app.DOS.ButtonPushedFcn = createCallbackFcn(app, @DOS_Callback, true);
            app.DOS.Tag = 'DOS';
            app.DOS.Text = '2';
            app.PUNTO = uibutton(app.uipanel1, 'push');
            app.PUNTO.ButtonPushedFcn = createCallbackFcn(app, @PUNTO_Callback, true);
            app.PUNTO.Tag = 'PUNTO';
            app.PUNTO.Text = '.';
            app.SEIS = uibutton(app.uipanel1, 'push');
            app.SEIS.ButtonPushedFcn = createCallbackFcn(app, @SEIS_Callback, true);
            app.SEIS.Tag = 'SEIS';
            app.SEIS.Text = '6';
            app.TRES = uibutton(app.uipanel1, 'push');
            app.TRES.ButtonPushedFcn = createCallbackFcn(app, @TRES_Callback, true);
            app.TRES.Tag = 'TRES';
            app.TRES.Text = '3';
            app.IGUAL = uibutton(app.uipanel1, 'push');
            app.IGUAL.ButtonPushedFcn = createCallbackFcn(app, @IGUAL_Callback, true);
            app.IGUAL.Tag = 'IGUAL';
            app.IGUAL.Text = '=';
            app.MENOS = uibutton(app.uipanel1, 'push');
            app.MENOS.ButtonPushedFcn = createCallbackFcn(app, @MENOS_Callback, true);
            app.MENOS.Tag = 'MENOS';
            app.MENOS.Text = '-';
            app.MAS = uibutton(app.uipanel1, 'push');
            app.MAS.ButtonPushedFcn = createCallbackFcn(app, @MAS_Callback, true);
            app.MAS.Tag = 'MAS';
            app.MAS.Text = '+';
            app.SIETE = uibutton(app.uipanel1, 'push');
            app.SIETE.ButtonPushedFcn = createCallbackFcn(app, @SIETE_Callback, true);
            app.SIETE.Tag = 'SIETE';
            app.SIETE.Text = '7';
            app.OCHO = uibutton(app.uipanel1, 'push');
            app.OCHO.ButtonPushedFcn = createCallbackFcn(app, @OCHO_Callback, true);
            app.OCHO.Tag = 'OCHO';
            app.OCHO.Text = '8';
            app.NUEVE = uibutton(app.uipanel1, 'push');
            app.NUEVE.ButtonPushedFcn = createCallbackFcn(app, @NUEVE_Callback, true);
            app.NUEVE.Tag = 'NUEVE';
            app.NUEVE.Text = '9';
            app.MULTIPLICAR = uibutton(app.uipanel1, 'push');
            app.MULTIPLICAR.ButtonPushedFcn = createCallbackFcn(app, @MULTIPLICAR_Callback, true);
            app.MULTIPLICAR.Tag = 'MULTIPLICAR';
            app.MULTIPLICAR.Text = '*';
            app.DIVIDIR = uibutton(app.uipanel1, 'push');
            app.DIVIDIR.ButtonPushedFcn = createCallbackFcn(app, @DIVIDIR_Callback, true);
            app.DIVIDIR.Tag = 'DIVIDIR';
            app.DIVIDIR.Text = '/';
            app.RESULTADO = uilabel(app.uipanel1);
            app.RESULTADO.Tag = 'RESULTADO';
            app.RESULTADO.Text = '';
            app.ABRIRCIENTIIFICA = uibutton(app.uipanel1, 'push');
            app.ABRIRCIENTIIFICA.ButtonPushedFcn = createCallbackFcn(app, @ABRIRCIENTIIFICA_Callback, true);
            app.ABRIRCIENTIIFICA.Tag = 'ABRIRCIENTIIFICA';
            app.ABRIRCIENTIIFICA.Text = 'CIENTIFICA';
            app.BORRAR = uibutton(app.uipanel1, 'push');
            app.BORRAR.ButtonPushedFcn = createCallbackFcn(app, @BORRAR_Callback, true);
            app.BORRAR.Tag = 'BORRAR';
            app.BORRAR.Text = 'AC';
            app.PORCIENTO = uibutton(app.uipanel1, 'push');
            app.PORCIENTO.ButtonPushedFcn = createCallbackFcn(app, @PORCIENTO_Callback, true);
            app.PORCIENTO.Tag = 'PORCIENTO';
            app.PORCIENTO.Text = '%';
            app.RAIZ = uibutton(app.uipanel1, 'push');
            app.RAIZ.ButtonPushedFcn = createCallbackFcn(app, @RAIZ_Callback, true);
            app.RAIZ.Tag = 'RAIZ';
            app.RAIZ.Text = '²√';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CALCULADORAUMG_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)CALCULADORAUMG_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end