classdef Calcular_Perimetro_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        Txtresultado  matlab.ui.control.EditField
        text7         matlab.ui.control.Label
        pushbutton1   matlab.ui.control.Button
        Txtlado4      matlab.ui.control.EditField
        txtlado3      matlab.ui.control.EditField
        Txtlado2      matlab.ui.control.EditField
        Txtlado1      matlab.ui.control.EditField
        text6         matlab.ui.control.Label
        text5         matlab.ui.control.Label
        text4         matlab.ui.control.Label
        text3         matlab.ui.control.Label
        text2         matlab.ui.control.Label
    end

    
    methods (Access = private)
        function pushbutton2_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pushbutton2.
            
            close Calcular_Perimetro
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Calcular_Perimetro_OpeningFcn(app, varargin)
            % --- Executes just before Calcular_Perimetro is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Calcular_Perimetro (see VARARGIN)
            
            % Choose default command line output for Calcular_Perimetro
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            lado1=eval(get(handles.Txtlado1,'String'));
            lado2=eval(get(handles.Txtlado2,'String'));
            lado3=eval(get(handles.txtlado3,'String'));
            lado4=eval(get(handles.Txtlado4,'String'));
            total=lado1+lado2+lado3+lado4;
            
            set(handles.Txtresultado,'String',total);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Calcular_Perimetro';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = {'PERIMETRO DE UN CUADRILATERO'; ''};
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = {'LADO 1'; ''};
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = {'LADO 2'; ''};
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'LADO 3';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'LADO 4';
            app.Txtlado1 = uieditfield(app.figure1, 'text');
            app.Txtlado1.Tag = 'Txtlado1';
            app.Txtlado2 = uieditfield(app.figure1, 'text');
            app.Txtlado2.Tag = 'Txtlado2';
            app.txtlado3 = uieditfield(app.figure1, 'text');
            app.txtlado3.Tag = 'txtlado3';
            app.Txtlado4 = uieditfield(app.figure1, 'text');
            app.Txtlado4.Tag = 'Txtlado4';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'CALCULAR';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = {'RESULTADO'; ''};
            app.Txtresultado = uieditfield(app.figure1, 'text');
            app.Txtresultado.Tag = 'Txtresultado';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calcular_Perimetro_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Calcular_Perimetro_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end