classdef ConvertidorTemperatura_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        btnsalir      matlab.ui.control.Button
        btncalcular3  matlab.ui.control.Button
        btncalcular2  matlab.ui.control.Button
        Btncalcular   matlab.ui.control.Button
        TxtFarenheit  matlab.ui.control.EditField
        TxtKelvin     matlab.ui.control.EditField
        TxtCelcius    matlab.ui.control.EditField
        text5         matlab.ui.control.Label
        text4         matlab.ui.control.Label
        text3         matlab.ui.control.Label
        text2         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ConvertidorTemperatura_OpeningFcn(app, varargin)
            % --- Executes just before ConvertidorTemperatura is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to ConvertidorTemperatura (see VARARGIN)
            
            % Choose default command line output for ConvertidorTemperatura
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Btncalcular
        function Btncalcular_Callback(app, event)
            % --- Executes on button press in Btncalcular.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            micelcius=eval(get(handles.TxtCelcius,'String'));
            mikelvin=micelcius+273.15;
            mifarenheit=1.8*micelcius+32;
            
            set(handles.TxtKelvin,'String',mikelvin);
            set(handles.TxtFarenheit,'String', mifarenheit);
        end

        % Button pushed function: btncalcular2
        function btncalcular2_Callback(app, event)
            % --- Executes on button press in btncalcular2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            mikelvin=eval(get(handles.TxtKelvin,'String'));
            mifarenheit=(mikelvin-273.15)*9/5+32;
            micelcius=mikelvin-273.15;
            
            set(handles.TxtFarenheit,'String',mifarenheit);
            set(handles.TxtCelcius,'String',micelcius);
        end

        % Button pushed function: btncalcular3
        function btncalcular3_Callback(app, event)
            % --- Executes on button press in btncalcular3.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            mifarenheit=eval(get(handles.TxtFarenheit,'String'));
            mikelvin=(mifarenheit-32)*5/9+273.15;
            micelcius=(mifarenheit-32)*5/9;
            
            set(handles.TxtKelvin,'String',mikelvin);
            set(handles.TxtCelcius,'String',micelcius);
        end

        % Button pushed function: btnsalir
        function btnsalir_Callback(app, event)
            % --- Executes on button press in btnsalir.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            close ConvertidorTemperatura
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ConvertidorTemperatura';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'CONVERTIDOR DE TEMPERATURAS';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Celcius';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Kelvin';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Farenheit';
            app.TxtCelcius = uieditfield(app.figure1, 'text');
            app.TxtCelcius.Tag = 'TxtCelcius';
            app.TxtKelvin = uieditfield(app.figure1, 'text');
            app.TxtKelvin.Tag = 'TxtKelvin';
            app.TxtFarenheit = uieditfield(app.figure1, 'text');
            app.TxtFarenheit.Tag = 'TxtFarenheit';
            app.Btncalcular = uibutton(app.figure1, 'push');
            app.Btncalcular.ButtonPushedFcn = createCallbackFcn(app, @Btncalcular_Callback, true);
            app.Btncalcular.Tag = 'Btncalcular';
            app.Btncalcular.Text = 'Calcular';
            app.btncalcular2 = uibutton(app.figure1, 'push');
            app.btncalcular2.ButtonPushedFcn = createCallbackFcn(app, @btncalcular2_Callback, true);
            app.btncalcular2.Tag = 'btncalcular2';
            app.btncalcular2.Text = ' calcular';
            app.btncalcular3 = uibutton(app.figure1, 'push');
            app.btncalcular3.ButtonPushedFcn = createCallbackFcn(app, @btncalcular3_Callback, true);
            app.btncalcular3.Tag = 'btncalcular3';
            app.btncalcular3.Text = 'calcular';
            app.btnsalir = uibutton(app.figure1, 'push');
            app.btnsalir.ButtonPushedFcn = createCallbackFcn(app, @btnsalir_Callback, true);
            app.btnsalir.Tag = 'btnsalir';
            app.btnsalir.Text = 'Salir';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConvertidorTemperatura_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)ConvertidorTemperatura_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end