classdef EcuDiferen_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel1     matlab.ui.container.Panel
        text6        matlab.ui.control.Label
        text5        matlab.ui.control.Label
        resulCon     matlab.ui.control.EditField
        resulSin     matlab.ui.control.EditField
        pushbutton1  matlab.ui.control.Button
        condicion    matlab.ui.control.EditField
        text4        matlab.ui.control.Label
        ecuacion     matlab.ui.control.EditField
        text3        matlab.ui.control.Label
        text2        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function IneDiferen_OpeningFcn(app, varargin)
            % --- Executes just before IneDiferen is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            axis off
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to IneDiferen (see VARARGIN)
            
            % Choose default command line output for IneDiferen
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            clc;
            a=get(handles.ecuacion, 'string');
            b=get(handles.condicion, 'string');
            syms x y;
            if (b=='0')
                c=dsolve(a,'x')
                set(handles.resulSin, 'string', char(c));
            else
                c=dsolve(a,'x')
                set(handles.resulSin, 'string', char(c));
                d=dsolve(a,b,'x')
                set(handles.resulCon, 'string', char(d));
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'IneDiferen';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Ecuaciones Diferenciales';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Ingrese la ecuación';
            app.ecuacion = uieditfield(app.figure1, 'text');
            app.ecuacion.Tag = 'ecuacion';
            app.ecuacion.Value = '0';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Ingrese la condición';
            app.condicion = uieditfield(app.figure1, 'text');
            app.condicion.Tag = 'condicion';
            app.condicion.Value = '0';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Resolver ';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Resultados';
            app.uipanel1.Tag = 'uipanel1';
            app.resulSin = uieditfield(app.uipanel1, 'text');
            app.resulSin.Tag = 'resulSin';
            app.resulCon = uieditfield(app.uipanel1, 'text');
            app.resulCon.Tag = 'resulCon';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Sin condición: y=';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Con condición: y=';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EcuDiferen_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)IneDiferen_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end