classdef EcuLine_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1    matlab.ui.Figure
        text5      matlab.ui.control.Label
        uipanel1   matlab.ui.container.Panel
        resultado  matlab.ui.control.Label
        resolver   matlab.ui.control.Button
        TxtInecua  matlab.ui.control.EditField
        text2      matlab.ui.control.Label
    end

    
    methods (Access = private)
        function salir_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in salir.
            
            
            % hObject    handle to salir (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA).
            close salir;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function IneLine_OpeningFcn(app, varargin)
            % --- Executes just before IneLine is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            axis off
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to IneLine (see VARARGIN)
            
            % Choose default command line output for IneLine
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: resolver
        function resolver_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            syms x;
            syms y;
            a=eval(get(handles.TxtInecua,'string'));
            c=solve(a,'real', true)
            % c.conditions;
            %,'returnconditions', true)
            set(handles.resultado, 'string', char(c));
            
            clc;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'IneLine';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'CALCULADORA DE ECUACIONES LINEALES';
            app.TxtInecua = uieditfield(app.figure1, 'text');
            app.TxtInecua.Tag = 'TxtInecua';
            app.resolver = uibutton(app.figure1, 'push');
            app.resolver.ButtonPushedFcn = createCallbackFcn(app, @resolver_Callback, true);
            app.resolver.Tag = 'resolver';
            app.resolver.Text = 'Resolver';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Resultado';
            app.uipanel1.Tag = 'uipanel1';
            app.resultado = uilabel(app.uipanel1);
            app.resultado.Tag = 'resultado';
            app.resultado.Text = '';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Ingrese la ecuacion';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EcuLine_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)IneLine_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end