classdef Finanzas_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        text7        matlab.ui.control.Label
        uipanel1     matlab.ui.container.Panel
        pushbutton2  matlab.ui.control.Button
        pushbutton1  matlab.ui.control.Button
        text6        matlab.ui.control.Label
        text5        matlab.ui.control.Label
        text4        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        text2        matlab.ui.control.Label
        edit3        matlab.ui.control.EditField
        edit2        matlab.ui.control.EditField
        edit1        matlab.ui.control.EditField
        axes3        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Finanzas_OpeningFcn(app, varargin)
            % --- Executes just before Finanzas is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            axes('Units','Normalized','Position',[0 0 1 1]);
            axis off
            axes(handles.axes3);
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Finanzas (see VARARGIN)
            
            % Choose default command line output for Finanzas
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Val=get(hObject,'string');
            NewVal=str2double(Val);
            handles.edit1=NewVal;
            guidata(hObject,handles);
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Val=get(hObject,'string');
            NewVal=str2double(Val);
            handles.edit2=NewVal;
            guidata(hObject,handles);
        end

        % Value changed function: edit3
        function edit3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Val=get(hObject,'string');
            NewVal=str2double(Val);
            handles.edit3=NewVal;
            guidata(hObject,handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            C=handles.edit1;
            n=handles.edit2;
            r=handles.edit3;
            
            cf=C*(1+n*r)/100;
            set(handles.text6,'string',cf);
        end

        % Button pushed function: pushbutton2
        function pushbutton2ButtonPushed(app, event)
            % Restablecer los valores de los campos de texto
    app.edit1.Value = '';
    app.edit2.Value = '';
    app.edit3.Value = '';
    
    % Limpiar el texto de la etiqueta que muestra el resultado
    app.text6.Text = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Finanzas';
            app.figure1.Tag = 'figure1';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.Tag = 'axes3';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Calculo Capital Final';
            app.uipanel1.Tag = 'uipanel1';
            app.edit1 = uieditfield(app.uipanel1, 'text');
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Tag = 'edit1';
            app.edit2 = uieditfield(app.uipanel1, 'text');
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Tag = 'edit2';
            app.edit3 = uieditfield(app.uipanel1, 'text');
            app.edit3.ValueChangedFcn = createCallbackFcn(app, @edit3_Callback, true);
            app.edit3.Tag = 'edit3';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'CAPITAL INICIAL';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'INTERESES';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'PERIODO';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'CAPITAL FINAL';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = '';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'CALCULAR';
            app.pushbutton2 = uibutton(app.uipanel1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2ButtonPushed, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'LIMPIAR';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'CAPITAL FINAL';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Finanzas_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Finanzas_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end