classdef Secante_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        text8          matlab.ui.control.Label
        text7          matlab.ui.control.Label
        ejecutar       matlab.ui.control.Button
        raiz           matlab.ui.control.Label
        iteraciones    matlab.ui.control.ListBox
        text5          matlab.ui.control.Label
        text4          matlab.ui.control.Label
        text3          matlab.ui.control.Label
        error          matlab.ui.control.Label
        edit4          matlab.ui.control.EditField
        valorSuperior  matlab.ui.control.EditField
        valorInferior  matlab.ui.control.EditField
        funcion        matlab.ui.control.EditField
        grafica        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Secante_OpeningFcn(app, varargin)
            % --- Executes just before Secante is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Secante (see VARARGIN)
            
            % Choose default command line output for Secante
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: ejecutar
        function ejecutar_Callback(app, event)
            % --- Executes on button press in ejecutar.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


            f=get(handles.funcion,'String');
            f=inline(f);
            x1=str2double(get(handles.valorInferior,'String'));
            x2=str2double(get(handles.valorSuperior,'String'));
            Error=str2double(get(handles.edit4,'String'));
            
            
            xlabel('Valores de (x)');
            ylabel('Valores de F(x)');


            ezplot(f);
            grid on
            xlabel('Valores de (x)');
            ylabel('Valores de F(x)');


            a=0;
            while abs(x2-x1)>Error
                x=x2-(((x2-x1)*f(x2))/(f(x2)-f(x1)));
            
                x1=x2;
                x2=x;
                a=a+1;
                Y{a}=x;
            
            end
            set(handles.raiz,'String',x);
            set(handles.iteraciones,'String',Y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Secante';
            app.figure1.Tag = 'figure1';
            app.grafica = uiaxes(app.figure1);
            app.grafica.Tag = 'grafica';
            app.funcion = uieditfield(app.figure1, 'text');
            app.funcion.Tag = 'funcion';
            app.valorInferior = uieditfield(app.figure1, 'text');
            app.valorInferior.Tag = 'valorInferior';
            app.valorSuperior = uieditfield(app.figure1, 'text');
            app.valorSuperior.Tag = 'valorSuperior';
            app.edit4 = uieditfield(app.figure1, 'text');
            app.edit4.Tag = 'edit4';
            app.error = uilabel(app.figure1);
            app.error.Tag = 'error';
            app.error.Text = 'Ɛs';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'f(x):';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'X-1:';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'X0:';
            app.iteraciones = uilistbox(app.figure1);
            app.iteraciones.Items = {'Iteraciones'};
            app.iteraciones.Tag = 'iteraciones';
            app.iteraciones.Value = 'Iteraciones';
            app.raiz = uilabel(app.figure1);
            app.raiz.Tag = 'raiz';
            app.raiz.Text = '';
            app.ejecutar = uibutton(app.figure1, 'push');
            app.ejecutar.ButtonPushedFcn = createCallbackFcn(app, @ejecutar_Callback, true);
            app.ejecutar.Tag = 'ejecutar';
            app.ejecutar.Text = 'Calcular Raiz';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'RAIZ';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'MÉTODO DE LA SECANTE';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Secante_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Secante_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end