classdef matriz_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        text6       matlab.ui.control.Label
        limpiar     matlab.ui.control.Button
        deter       matlab.ui.control.Button
        inversa     matlab.ui.control.Button
        resultado2  matlab.ui.container.Panel
        resultado   matlab.ui.control.Label
        uitable1    matlab.ui.control.Table
        insertar    matlab.ui.control.Button
        fila        matlab.ui.control.EditField
        columna     matlab.ui.control.EditField
        text5       matlab.ui.control.Label
        text4       matlab.ui.control.Label
        text3       matlab.ui.control.Label
        text2       matlab.ui.control.Label
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uitable1.BackgroundColor = [1 1 1;0.9412 0.9412 0.9412];
            app.uitable1.ColumnFormat = {[] []};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function matriz_OpeningFcn(app, varargin)
            % --- Executes just before matriz is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            s=imread('hermoso-fondo-acuarela-amarillo-elegante_1055-9879.jpg');
            image(s)
            axis off
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to matriz (see VARARGIN)
            
            % Choose default command line output for matriz
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: deter
        function deter_Callback(app, event)
            % --- Executes on button press in deter.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Mat=str2double(get(handles.uitable1,'Data'));
            [fila, columna]=size(Mat);
            if fila==columna
                E=det(Mat);
                set(handles.resultado,'String',num2str(E));
            else
                set(handles.resultado,'String','');
            end
        end

        % Button pushed function: insertar
        function insertar_Callback(app, event)
            % --- Executes on button press in insertar.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            fila=str2double(get(handles.fila,'String'));
            columna=str2double(get(handles.columna,'String'));
            tamano=cell(fila,columna);
            tamano(:,:)={''};
            set(handles.uitable1,'Data',tamano);
            set(handles.uitable1,'ColumnEditable',true(1,columna));
        end

        % Button pushed function: inversa
        function inversa_Callback(app, event)
            % --- Executes on button press in inversa.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Mat=str2double(get(handles.uitable1,'Data'));
            [fila, columna]=size(Mat);
            if fila==columna
                i=inv(Mat);
                set(handles.resultado,'String',num2str(i));
            else
                set(handles.resultado,'String','');
            end
        end

        % Button pushed function: limpiar
        function limpiar_Callback(app, event)
            % --- Executes on button press in limpiar.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            c=char(' ');
            set(handles.resultado,'string',c);
            %set(handles.uitable1, 'string', s);
            set(handles.fila,'string',c);
            set(handles.columna,'string',c);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'matriz';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'CALCULADORA DE MATRICES';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'TAMAÑO DE LA MATRIZ';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Columnas';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Filas';
            app.columna = uieditfield(app.figure1, 'text');
            app.columna.Tag = 'columna';
            app.fila = uieditfield(app.figure1, 'text');
            app.fila.Tag = 'fila';
            app.insertar = uibutton(app.figure1, 'push');
            app.insertar.ButtonPushedFcn = createCallbackFcn(app, @insertar_Callback, true);
            app.insertar.Tag = 'insertar';
            app.insertar.Text = 'Insertar';
            app.uitable1 = uitable(app.figure1);
            app.uitable1.ColumnEditable = [false false];
            app.uitable1.Tag = 'uitable1';
            app.resultado2 = uipanel(app.figure1);
            app.resultado2.Title = 'Resultado';
            app.resultado2.Tag = 'resultado2';
            app.resultado = uilabel(app.resultado2);
            app.resultado.Tag = 'resultado';
            app.resultado.Text = '';
            app.inversa = uibutton(app.figure1, 'push');
            app.inversa.ButtonPushedFcn = createCallbackFcn(app, @inversa_Callback, true);
            app.inversa.Tag = 'inversa';
            app.inversa.Text = 'INVERSA';
            app.deter = uibutton(app.figure1, 'push');
            app.deter.ButtonPushedFcn = createCallbackFcn(app, @deter_Callback, true);
            app.deter.Tag = 'deter';
            app.deter.Text = 'DETERMINANTE';
            app.limpiar = uibutton(app.figure1, 'push');
            app.limpiar.ButtonPushedFcn = createCallbackFcn(app, @limpiar_Callback, true);
            app.limpiar.Tag = 'limpiar';
            app.limpiar.Text = 'Limpiar';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'PERMITE 4x4';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matriz_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)matriz_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end